classdef do_an < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Tab2                           matlab.ui.container.Tab
        Tab3                           matlab.ui.container.Tab
        Tab4                           matlab.ui.container.Tab
        Tab4KetQuaTaylorEditField      matlab.ui.control.EditField
        PhngphpTaylorEditFieldLabel_2  matlab.ui.control.Label
        Tab4TDaThucNoiSuyTextArea      matlab.ui.control.TextArea
        athcnisuyLabel                 matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        Tab4HienGiaTriXTextArea        matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        Tab4KetQuaLagrangeEditField    matlab.ui.control.NumericEditField
        PhngphpLagrangeEditFieldLabel  matlab.ui.control.Label
        Tab4TinhKetQuaButton           matlab.ui.control.Button
        Tab4GiaTriXEditField           matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel     matlab.ui.control.Label
        Tab4DuLieuYEditField           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel         matlab.ui.control.Label
        Tab4DuLieuXEditField           matlab.ui.control.EditField
        NhpdliuxEditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Tab4TinhKetQuaButton
        function Tab4TinhKetQuaButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            xa = convertStringToNumArray(app.Tab4DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Tab4DuLieuYEditField.Value);
            x0 = app.Tab4GiaTriXEditField.Value;
            app.Tab4HienGiaTriXTextArea.Value = ['Giá trị đạo hàm tại x = ' num2str(x0)];
            
            % Tính đa thức nội suy Lagrange
            f_Lagrange = LagrangeSymbolic(xa, ya);
            % Hiển thị đa thức nội suy
            app.Tab4TDaThucNoiSuyTextArea.Value = char(f_Lagrange);
            % Tính giá trị đạo hàm tại x0
            f_dh = diff(f_Lagrange);
            f_dh_handle = matlabFunction(f_dh);
            result_Lagrange = f_dh_handle(x0);
            app.Tab4KetQuaLagrangeEditField.Value = result_Lagrange;

            % Tính giá trị đạo hàm tại x0 bằng phương pháp Taylor theo
            % chiều trung tâm
            result_Taylor = daohamTaylor(xa, ya, x0);
            app.Tab4KetQuaTaylorEditField.Value = num2str(result_Taylor);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.Tab4DuLieuXEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuXEditField.Value = '0.1 0.3 0.5 0.7 0.9';
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab4);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Tab4DuLieuYEditField = uieditfield(app.Tab4, 'text');
            app.Tab4DuLieuYEditField.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.Tab4);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.Tab4GiaTriXEditField = uieditfield(app.Tab4, 'numeric');
            app.Tab4GiaTriXEditField.Value = 0.14;
            app.Tab4TinhKetQuaButton = uibutton(app.Tab4, 'push');
            app.Tab4TinhKetQuaButton.ButtonPushedFcn = createCallbackFcn(app, @Tab4TinhKetQuaButtonPushed, true);
            app.PhngphpLagrangeEditFieldLabel = uilabel(app.Tab4);
            app.PhngphpLagrangeEditFieldLabel.Text = 'Phương pháp Lagrange';
            app.Tab4KetQuaLagrangeEditField = uieditfield(app.Tab4, 'numeric');
            app.TextAreaLabel = uilabel(app.Tab4);
            app.TextAreaLabel.Text = '';
            app.Tab4HienGiaTriXTextArea = uitextarea(app.Tab4);
            app.Tab4HienGiaTriXTextArea.Value = {'Giá trị đạo hàm tại x = '};
            app.TextArea_2 = uitextarea(app.Tab4);
            app.TextArea_2.Value = {'       Nhập dữ liệu'};
            app.athcnisuyLabel = uilabel(app.Tab4);
            app.athcnisuyLabel.Text = 'Đa thức nội suy';
            app.Tab4TDaThucNoiSuyTextArea = uitextarea(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2 = uilabel(app.Tab4);
            app.PhngphpTaylorEditFieldLabel_2.Text = 'Phương pháp Taylor';
            app.Tab4KetQuaTaylorEditField = uieditfield(app.Tab4, 'text');
            app.Tab4KetQuaTaylorEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = do_an

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end